classdef konrad_czarnowski_kalkulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        KalkulatorprostyTab             matlab.ui.container.Tab
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        Button_6                        matlab.ui.control.Button
        Button_7                        matlab.ui.control.Button
        Button_8                        matlab.ui.control.Button
        Button_9                        matlab.ui.control.Button
        Button_10                       matlab.ui.control.Button
        Button_11                       matlab.ui.control.Button
        Button_12                       matlab.ui.control.Button
        Button_13                       matlab.ui.control.Button
        Button_14                       matlab.ui.control.Button
        CButton                         matlab.ui.control.Button
        Button_15                       matlab.ui.control.Button
        WynikTextAreaLabel              matlab.ui.control.Label
        WynikTextArea                   matlab.ui.control.TextArea
        UkryjButton                     matlab.ui.control.Button
        sinButton                       matlab.ui.control.Button
        cosButton                       matlab.ui.control.Button
        sqrtButton_16                   matlab.ui.control.Button
        tanButton                       matlab.ui.control.Button
        lnButton                        matlab.ui.control.Button
        log10Button                     matlab.ui.control.Button
        Button_16                       matlab.ui.control.Button
        ctgButton                       matlab.ui.control.Button
        sinhButton                      matlab.ui.control.Button
        Label_6                         matlab.ui.control.Label
        log2                            matlab.ui.control.Button
        coshButton                      matlab.ui.control.Button
        tanhButton                      matlab.ui.control.Button
        ctghButton                      matlab.ui.control.Button
        pow                             matlab.ui.control.Button
        KalkulatorrownankwadratowychTab  matlab.ui.container.Tab
        aLabel                          matlab.ui.control.Label
        AEditField                      matlab.ui.control.NumericEditField
        bLabel                          matlab.ui.control.Label
        BEditField2                     matlab.ui.control.NumericEditField
        cLabel                          matlab.ui.control.Label
        CEditField3                     matlab.ui.control.NumericEditField
        deltaLabel                      matlab.ui.control.Label
        deltaEditField4                 matlab.ui.control.NumericEditField
        x1Label                         matlab.ui.control.Label
        X1                              matlab.ui.control.NumericEditField
        x2Label                         matlab.ui.control.Label
        X2                              matlab.ui.control.NumericEditField
        WynikButton                     matlab.ui.control.Button
        x0Label                         matlab.ui.control.Label
        X0                              matlab.ui.control.NumericEditField
        IlomiejsczerowychTextAreaLabel  matlab.ui.control.Label
        IlomiejsczerowychTextArea       matlab.ui.control.TextArea
        XLabel                          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        XLabel_2                        matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        KalkulatorrwnakwadratowychLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button, Button_10, Button_11, 
        % Button_12, Button_13, Button_14, Button_2, Button_3, 
        % Button_4, Button_5, Button_6, Button_7, Button_8, Button_9
        function ButtonPushed(app, event)
          app.WynikTextArea.Value = [app.WynikTextArea.Value{1} event.Source.Text]; % wyświetlanie numeru wciśniętego przycisku i znaku wybranego dzialania w polu tekstowym
            
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            app.WynikTextArea.Value = ''; % czyszczenie pola tekstowego (C-clear (ang. czysc))
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            app.WynikTextArea.Value = [app.WynikTextArea.Value{1} event.Source.Text num2str(eval(app.WynikTextArea.Value{1}))];% wyswietlanie wyniku dzialania
        end

        % Value changed function: deltaEditField4
        function deltaEditField4ValueChanged(app, event)
           
        end

        % Button pushed function: WynikButton
        function WynikButtonPushed(app, event)
             % kalkulator rownan kwadratowych
            A=app.AEditField.Value; %przypisanie zmiennej A do wartosci wpisanej w edytowalne pole tekstowe
            B=app.BEditField2.Value;%przypisanie zmiennej B do wartosci wpisanej w edytowalne pole tekstowe
            C=app.CEditField3.Value;%przypisanie zmiennej C do wartosci wpisanej w edytowalne pole tekstowe
              delta=B^2-4*A*C; % rownanie zmiennej delta
            x1=((-1)*B-sqrt(delta))/(2*A); %rownanie miejsca zerowego x1
            x2=((-1)*B+sqrt(delta))/(2*A); %rownanie miejsca zerowego x2
            x0=((-1)*B)/(2*A); %rownanie miejsca zerowego x3
         if delta > 0 % przypadek delty wiekszej od 0  
             app.deltaEditField4.Value=delta; %wyswietlenie wyniku obliczonej delty
            app.X1.Value=x1; %wyswietlenie wyniku obliczonego rozwiazania x1
            app.X2.Value=x2; %wyswietlenie wyniku obliczonego rozwiazania x2
            app.X0.Value=0; %wyswietlenie wyniku obliczonego rozwiazania x0
            app.IlomiejsczerowychTextArea.Value='2 miejsca zerowe (patrz x1 i x2)'; %wyswietlenie w polu informujacym o ilosci miejsc zerowych komunikatu:"2 miejsca zerowe(patrz x1 i x2)"
         end
         if delta == 0 % przypadek delty rownej 0
             app.deltaEditField4.Value=delta; %wyswietlenie wyniku obliczonej delty
             app.X0.Value=x0;  %wyswietlenie wyniku obliczonego rozwiazania x0
            app.X1.Value=0; %wyswietlenie w polu x1 wartosci 0
            app.X2.Value=0; %wyswietlenie w polu x2 wartosci 0
            
            app.IlomiejsczerowychTextArea.Value='1 miejsce zerowe (patrz x0)'; %wyswietlenie w polu informujacym o ilosci miejsc zerowych komunikatu:"1 miejsce zerowe"
            
         end
        if delta < 0 % przypadek delty mniejszej od 0
             app.deltaEditField4.Value=delta; %wyswietlenie wyniku obliczonej delty
           app.X0.Value=0; %wyswietlenie w polu x0 wartosci 0
            app.X1.Value=0; %wyswietlenie w polu x1 wartosci 0
            app.X2.Value=0; %wyswietlenie w polu x2 wartosci 0
            app.IlomiejsczerowychTextArea.Value='brak miejsc zerowych'; %wyswietlenie w polu informujacym o ilosci miejsc zerowych komunikatu:"rak miejsc zerrowych"
         end

           
        end

        % Button pushed function: UkryjButton
        function UkryjButtonPushed(app, event)
            switch(app.UkryjButton.Text) % znikanie i pojawianie się dodatkowych funkcji kalkulatora prostego po kliknieciu przycisku "UkryjButton"
                case 'Ukryj'
                    app.UkryjButton.Text = 'Dodatkowe funkcje';
                    app.sinButton.Visible = 'off';
                    app.tanButton.Visible = 'off';
                    app.cosButton.Visible = 'off';
                    app.ctgButton.Visible = 'off';
                    app.sinhButton.Visible = 'off';
                    app.tanhButton.Visible = 'off';
                    app.coshButton.Visible = 'off';
                    app.ctghButton.Visible = 'off';
                    app.Label_6.Visible = 'off';
                    app.sqrtButton_16.Visible= 'off';
                    app.log10Button.Visible= 'off';
                    app.lnButton.Visible= 'off';
                    app.Button_16.Visible= 'off';
                    app.log2.Visible= 'off';
                    app.pow.Visible= 'off';
                    
                    
                case 'Dodatkowe funkcje' % znikanie i pojawianie się dodatkowych funkcji kalkulatora prostego po kliknieciu przycisku "UkryjButton"
                   app.UkryjButton.Text = 'Ukryj';
                    app.sinButton.Visible = 'on';
                    app.tanButton.Visible = 'on';
                    app.cosButton.Visible = 'on';
                    app.ctgButton.Visible = 'on';
                    app.sinhButton.Visible = 'on';
                    app.tanhButton.Visible = 'on';
                    app.coshButton.Visible = 'on';
                    app.ctghButton.Visible = 'on';
                    app.Label_6.Visible = 'on';
                    app.sqrtButton_16.Visible= 'on';
                    app.log10Button.Visible= 'on';
                    app.lnButton.Visible= 'on';
                    app.Button_16.Visible= 'on';
                    app.log2.Visible= 'on';
                    app.pow.Visible= 'on';
            end
        end

        % Button pushed function: sinButton
        function sinButtonPushed(app, event)
            app.WynikTextArea.Value=num2str(sin(str2double(app.WynikTextArea.Value{1}))); %funkcja liczaca sinus liczby wprowadzonej na klawiaturze numerycznej kalkulatora prostego, zrezalizowana w oparciu o wbudowane funkcje matematyczne matlaba
            
        end

        % Button pushed function: cosButton
        function cosButtonPushed(app, event)
            app.WynikTextArea.Value=num2str(cos(str2double(app.WynikTextArea.Value{1}))); %funkcja liczaca cosinus liczby wprowadzonej na klawiaturze numerycznej kalkulatora prostego, zrezalizowana w oparciu o wbudowane funkcje matematyczne matlaba
        end

        % Button pushed function: sqrtButton_16
        function sqrtButton_16Pushed(app, event)
            app.WynikTextArea.Value=num2str(sqrt(str2double(app.WynikTextArea.Value{1}))); %funkcja liczaca pierwiastek z liczby wprowadzonej na klawiaturze numerycznej kalkulatora prostego, zrezalizowana w oparciu o wbudowane funkcje matematyczne matlaba
        end

        % Value changed function: X1
        function X1ValueChanged(app, event)
            
        end

        % Button pushed function: tanButton
        function tanButtonPushed(app, event)
            app.WynikTextArea.Value=num2str(tan(str2double(app.WynikTextArea.Value{1}))); %funkcja liczaca tangens liczby wprowadzonej na klawiaturze numerycznej kalkulatora prostego, zrezalizowana w oparciu o wbudowane funkcje matematyczne matlaba
        end

        % Button pushed function: lnButton
        function lnButtonPushed(app, event)
            app.WynikTextArea.Value=num2str(log(str2double(app.WynikTextArea.Value{1}))); %funkcja liczaca logarytm naturalny z liczby wprowadzonej na klawiaturze numerycznej kalkulatora prostego, zrezalizowana w oparciu o wbudowane funkcje matematyczne matlaba
        end

        % Button pushed function: log10Button
        function log10ButtonPushed(app, event)
            app.WynikTextArea.Value=num2str(log10(str2double(app.WynikTextArea.Value{1}))); %funkcja liczaca logarytm dziesietny liczby wprowadzonej na klawiaturze numerycznej kalkulatora prostego, zrezalizowana w oparciu o wbudowane funkcje matematyczne matlaba
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            app.WynikTextArea.Value=num2str(exp(str2double(app.WynikTextArea.Value{1})));% funkcja liczaca wartosc e^x gdzie x to liczba wprowadzona na klawiaturze numerycznej kalkulatora prostego, zrezalizowana w oparciu o wbudowane funkcje matematyczne matlaba
        end

        % Button pushed function: ctgButton
        function ctgButtonPushed(app, event)
            app.WynikTextArea.Value=num2str(cot(str2double(app.WynikTextArea.Value{1}))); %funkcja liczaca cotangens liczby wprowadzonej na klawiaturze numerycznej kalkulatora prostego, zrezalizowana w oparciu o wbudowane funkcje matematyczne matlaba
        end

        % Button pushed function: sinhButton
        function sinhButtonPushed(app, event)
            app.WynikTextArea.Value=num2str(sinh(str2double(app.WynikTextArea.Value{1}))); %funkcja liczaca sinus hiperboliczny liczby wprowadzonej na klawiaturze numerycznej kalkulatora prostego, zrezalizowana w oparciu o wbudowane funkcje matematyczne matlaba
        end

        % Button pushed function: log2
        function log2Pushed(app, event)
            app.WynikTextArea.Value=num2str(log2(str2double(app.WynikTextArea.Value{1}))); %funkcja liczaca logarytm przy podstawie 2 z liczby wprowadzonej na klawiaturze numerycznej kalkulatora prostego, zrezalizowana w oparciu o wbudowane funkcje matematyczne matlaba
        end

        % Button pushed function: coshButton
        function coshButtonPushed(app, event)
            app.WynikTextArea.Value=num2str(cosh(str2double(app.WynikTextArea.Value{1})));%funkcja liczaca cosinus hiperboliczny liczby wprowadzonej na klawiaturze numerycznej kalkulatora prostego, zrezalizowana w oparciu o wbudowane funkcje matematyczne matlaba
        end

        % Button pushed function: tanhButton
        function tanhButtonPushed(app, event)
            app.WynikTextArea.Value=num2str(tanh(str2double(app.WynikTextArea.Value{1}))); %funkcja liczaca tangens hiperboliczny liczby wprowadzonej na klawiaturze numerycznej kalkulatora prostego, zrezalizowana w oparciu o wbudowane funkcje matematyczne matlaba
        end

        % Button pushed function: ctghButton
        function ctghButtonPushed(app, event)
            app.WynikTextArea.Value=num2str(coth(str2double(app.WynikTextArea.Value{1})));%funkcja liczaca cotangens hiperboliczny liczby wprowadzonej na klawiaturze numerycznej kalkulatora prostego, zrezalizowana w oparciu o wbudowane funkcje matematyczne matlaba
        end

        % Button pushed function: pow
        function powPushed(app, event)
            app.WynikTextArea.Value=num2str(pow2(str2double(app.WynikTextArea.Value{1})));%funkcja liczaca potęgi liczby 2, numer potęgi to liczba wprowadzona na klawiaturze numerycznej kalkulatora prostego, zrezalizowana w oparciu o wbudowane funkcje matematyczne matlaba
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.KalkulatorprostyTab = uitab(app.TabGroup);
            app.KalkulatorprostyTab.Title = 'Kalkulator prosty';
            app.Button = uibutton(app.KalkulatorprostyTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.KalkulatorprostyTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.KalkulatorprostyTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.KalkulatorprostyTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.KalkulatorprostyTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.KalkulatorprostyTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.KalkulatorprostyTab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.KalkulatorprostyTab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.KalkulatorprostyTab, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.KalkulatorprostyTab, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_10.Text = '0';
            app.Button_11 = uibutton(app.KalkulatorprostyTab, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_11.Text = '+';
            app.Button_12 = uibutton(app.KalkulatorprostyTab, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_12.Text = '-';
            app.Button_13 = uibutton(app.KalkulatorprostyTab, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_13.Text = '*';
            app.Button_14 = uibutton(app.KalkulatorprostyTab, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_14.Text = '/';
            app.CButton = uibutton(app.KalkulatorprostyTab, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.Button_15 = uibutton(app.KalkulatorprostyTab, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '=';
            app.WynikTextAreaLabel = uilabel(app.KalkulatorprostyTab);
            app.WynikTextAreaLabel.Text = 'Wynik';
            app.WynikTextArea = uitextarea(app.KalkulatorprostyTab);
            app.UkryjButton = uibutton(app.KalkulatorprostyTab, 'push');
            app.UkryjButton.ButtonPushedFcn = createCallbackFcn(app, @UkryjButtonPushed, true);
            app.UkryjButton.Text = 'Ukryj';
            app.sinButton = uibutton(app.KalkulatorprostyTab, 'push');
            app.sinButton.ButtonPushedFcn = createCallbackFcn(app, @sinButtonPushed, true);
            app.sinButton.Text = 'sin';
            app.cosButton = uibutton(app.KalkulatorprostyTab, 'push');
            app.cosButton.ButtonPushedFcn = createCallbackFcn(app, @cosButtonPushed, true);
            app.cosButton.Text = 'cos';
            app.sqrtButton_16 = uibutton(app.KalkulatorprostyTab, 'push');
            app.sqrtButton_16.ButtonPushedFcn = createCallbackFcn(app, @sqrtButton_16Pushed, true);
            app.sqrtButton_16.Icon = 'efwegfw.png';
            app.sqrtButton_16.Text = '';
            app.tanButton = uibutton(app.KalkulatorprostyTab, 'push');
            app.tanButton.ButtonPushedFcn = createCallbackFcn(app, @tanButtonPushed, true);
            app.tanButton.Text = 'tan';
            app.lnButton = uibutton(app.KalkulatorprostyTab, 'push');
            app.lnButton.ButtonPushedFcn = createCallbackFcn(app, @lnButtonPushed, true);
            app.lnButton.Text = 'ln';
            app.log10Button = uibutton(app.KalkulatorprostyTab, 'push');
            app.log10Button.ButtonPushedFcn = createCallbackFcn(app, @log10ButtonPushed, true);
            app.log10Button.Text = 'log10';
            app.Button_16 = uibutton(app.KalkulatorprostyTab, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Icon = 'ex.png';
            app.Button_16.Text = '';
            app.ctgButton = uibutton(app.KalkulatorprostyTab, 'push');
            app.ctgButton.ButtonPushedFcn = createCallbackFcn(app, @ctgButtonPushed, true);
            app.ctgButton.Text = 'ctg';
            app.sinhButton = uibutton(app.KalkulatorprostyTab, 'push');
            app.sinhButton.ButtonPushedFcn = createCallbackFcn(app, @sinhButtonPushed, true);
            app.sinhButton.Text = 'sinh';
            app.Label_6 = uilabel(app.KalkulatorprostyTab);
            app.Label_6.Text = 'Wyniki dla obliczanych funkcji trygonometrcznych sa podawane w radianach.';
            app.log2 = uibutton(app.KalkulatorprostyTab, 'push');
            app.log2.ButtonPushedFcn = createCallbackFcn(app, @log2Pushed, true);
            app.log2.Icon = 'log2.png';
            app.log2.Text = '';
            app.coshButton = uibutton(app.KalkulatorprostyTab, 'push');
            app.coshButton.ButtonPushedFcn = createCallbackFcn(app, @coshButtonPushed, true);
            app.coshButton.Text = 'cosh';
            app.tanhButton = uibutton(app.KalkulatorprostyTab, 'push');
            app.tanhButton.ButtonPushedFcn = createCallbackFcn(app, @tanhButtonPushed, true);
            app.tanhButton.Text = 'tanh';
            app.ctghButton = uibutton(app.KalkulatorprostyTab, 'push');
            app.ctghButton.ButtonPushedFcn = createCallbackFcn(app, @ctghButtonPushed, true);
            app.ctghButton.Text = 'ctgh';
            app.pow = uibutton(app.KalkulatorprostyTab, 'push');
            app.pow.ButtonPushedFcn = createCallbackFcn(app, @powPushed, true);
            app.pow.Icon = '2x.jpg';
            app.pow.Text = '';
            app.KalkulatorrownankwadratowychTab = uitab(app.TabGroup);
            app.KalkulatorrownankwadratowychTab.Title = 'Kalkulator rownan kwadratowych';
            app.aLabel = uilabel(app.KalkulatorrownankwadratowychTab);
            app.aLabel.Text = 'a';
            app.AEditField = uieditfield(app.KalkulatorrownankwadratowychTab, 'numeric');
            app.bLabel = uilabel(app.KalkulatorrownankwadratowychTab);
            app.bLabel.Text = 'b';
            app.BEditField2 = uieditfield(app.KalkulatorrownankwadratowychTab, 'numeric');
            app.cLabel = uilabel(app.KalkulatorrownankwadratowychTab);
            app.cLabel.Text = 'c';
            app.CEditField3 = uieditfield(app.KalkulatorrownankwadratowychTab, 'numeric');
            app.deltaLabel = uilabel(app.KalkulatorrownankwadratowychTab);
            app.deltaLabel.Text = 'delta';
            app.deltaEditField4 = uieditfield(app.KalkulatorrownankwadratowychTab, 'numeric');
            app.deltaEditField4.ValueChangedFcn = createCallbackFcn(app, @deltaEditField4ValueChanged, true);
            app.x1Label = uilabel(app.KalkulatorrownankwadratowychTab);
            app.x1Label.Text = 'x1';
            app.X1 = uieditfield(app.KalkulatorrownankwadratowychTab, 'numeric');
            app.X1.ValueChangedFcn = createCallbackFcn(app, @X1ValueChanged, true);
            app.x2Label = uilabel(app.KalkulatorrownankwadratowychTab);
            app.x2Label.Text = 'x2';
            app.X2 = uieditfield(app.KalkulatorrownankwadratowychTab, 'numeric');
            app.WynikButton = uibutton(app.KalkulatorrownankwadratowychTab, 'push');
            app.WynikButton.ButtonPushedFcn = createCallbackFcn(app, @WynikButtonPushed, true);
            app.WynikButton.Text = 'ROZWIĄŻ';
            app.x0Label = uilabel(app.KalkulatorrownankwadratowychTab);
            app.x0Label.Text = 'x0';
            app.X0 = uieditfield(app.KalkulatorrownankwadratowychTab, 'numeric');
            app.IlomiejsczerowychTextAreaLabel = uilabel(app.KalkulatorrownankwadratowychTab);
            app.IlomiejsczerowychTextAreaLabel.Text = 'Ilość miejsc zerowych:';
            app.IlomiejsczerowychTextArea = uitextarea(app.KalkulatorrownankwadratowychTab);
            app.XLabel = uilabel(app.KalkulatorrownankwadratowychTab);
            app.XLabel.Text = 'X';
            app.Label = uilabel(app.KalkulatorrownankwadratowychTab);
            app.Label.Text = '2';
            app.XLabel_2 = uilabel(app.KalkulatorrownankwadratowychTab);
            app.XLabel_2.Text = 'X';
            app.Label_5 = uilabel(app.KalkulatorrownankwadratowychTab);
            app.Label_5.Text = '0';
            app.Label_2 = uilabel(app.KalkulatorrownankwadratowychTab);
            app.Label_2.Text = '+';
            app.Label_3 = uilabel(app.KalkulatorrownankwadratowychTab);
            app.Label_3.Text = '+';
            app.Label_4 = uilabel(app.KalkulatorrownankwadratowychTab);
            app.Label_4.Text = '=';
            app.KalkulatorrwnakwadratowychLabel = uilabel(app.KalkulatorrownankwadratowychTab);
            app.KalkulatorrwnakwadratowychLabel.Text = 'Kalkulator równań kwadratowych. Wprowadź współczynniki rowania kwadratowego i rozwiąż równanie.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = konrad_czarnowski_kalkulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end